classdef lab2_Nemkovich < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FunctionUIAxes                matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        PopulationSizeEditField       matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        GensCountEditField            matlab.ui.control.NumericEditField
        EditField_3Label              matlab.ui.control.Label
        CrossingoverProbabilityEditField  matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        MutationProbabilityEditField  matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        MaxGenerationNumberEditField  matlab.ui.control.NumericEditField
        MiddleAndTopFitnessUIAxes     matlab.ui.control.UIAxes
        ApplySettingsButton           matlab.ui.control.Button
        NextGenerationButton          matlab.ui.control.Button
        X1Label                       matlab.ui.control.Label
        FLabel                        matlab.ui.control.Label
        label                         matlab.ui.control.Label
        X1ValueLabel                  matlab.ui.control.Label
        FValueLabel                   matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        EditField_2Label              matlab.ui.control.Label
        SearchLeftXEditField          matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        SearchRightXEditField         matlab.ui.control.NumericEditField
        label_2                       matlab.ui.control.Label
        CurrentGenerationNumberLabel  matlab.ui.control.Label
        X2Label                       matlab.ui.control.Label
        X2ValueLabel                  matlab.ui.control.Label
        PauseButton                   matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        Label_6                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Population properties
        PopulationSize = 0
        ChromosomeCount = 2
        GensCount = 0
        MaxGenerationNumber = 0
        MinTValue = -100
        MaxTValue = 100
        
        CrossingoverProbability = 0
        MutationProbability = 0
        
        % Population state
        Population
        MiddleValues=[];
        TopValues=[];
        Stop = 1;
    end
    
    methods (Access = private)
        
        function readSettings(app)
            app.PopulationSize = app.PopulationSizeEditField.Value;
            app.GensCount = app.GensCountEditField.Value;
            app.CrossingoverProbability = app.CrossingoverProbabilityEditField.Value;
            app.MutationProbability = app.MutationProbabilityEditField.Value;
            app.MaxGenerationNumber = app.MaxGenerationNumberEditField.Value;
            app.MinTValue = app.SearchLeftXEditField.Value;
            app.MaxTValue = app.SearchRightXEditField.Value;
        end
        
        function generateInitialGeneration(app)
            app.Population = population( ...
                app.PopulationSize, ...
                app.ChromosomeCount, ...
                app.GensCount, ...
                app.MaxGenerationNumber, ...
                app.MinTValue, ...
                app.MaxTValue);
        end
        
        function goToNextGeneration(app)
            if isa(app.Population, 'population') && app.Population.GenerationNumber >= 1
                app.Population = app.Population.goToNextGeneration( ...
                    app.CrossingoverProbability, ...
                    app.MutationProbability);    
            end
        end
        
        function reset(app, stopParallel)
            doStop = true;
            
            if nargin > 1
                doStop = stopParallel;
            end
            
            if doStop
                app.Stop = 1;    
            end
            
            if isa(app.Population, 'population')
                app.Population = app.Population.reset();    
            end
            app.MiddleValues = [];
            app.TopValues = [];
        end
        
        function applySettings(app, stopParallel)
            doStop = true;
            
            if nargin > 1
                doStop = stopParallel;
            end
            
            reset(app, doStop);
            readSettings(app);
            generateInitialGeneration(app);
        end
        
        function runToMaxGenerationNumber(app)
            pauseTime = 0.2;
            
            pause on
            
            if false == isa(app.Population, 'population') || app.Population.GenerationNumber == 0
                applySettings(app, false);
                app.updateUI();
                pause(pauseTime);
            end
            
            for i=app.Population.GenerationNumber:app.MaxGenerationNumber-1
                if app.Stop > 0
                    app.Stop = 0;
                    break;
                end
                app.goToNextGeneration();
                app.updateUI();
                pause(pauseTime);
            end
            
            pause off
        end
        
        function updateUI(app)
            if false == isa(app.Population, 'population')
                return;
            end
            
            if app.Population.GenerationNumber <= 1
                cla(app.FunctionUIAxes);
            
                [X1,X2] = meshgrid(app.MinTValue:1:app.MaxTValue, app.MinTValue:1:app.MaxTValue);
                Z = FitnessFunction(X1,X2);
            
                contour(app.FunctionUIAxes, X1, X2, Z);
            end
            
            if app.Population.GenerationNumber > 0
                hold(app.FunctionUIAxes, 'on');
                
                bestIndivid = app.Population.BestIndivid;
                plot(app.FunctionUIAxes, bestIndivid.XValues(1), bestIndivid.XValues(2), 'Marker',"*","LineWidth",5);
                
                hold(app.FunctionUIAxes, 'off');
                
                app.CurrentGenerationNumberLabel.Text = int2str(app.Population.GenerationNumber);
                
                app.X1ValueLabel.Text = sprintf('%.3f', app.Population.BestIndivid.XValues(1));
                app.X2ValueLabel.Text = sprintf('%.3f', app.Population.BestIndivid.XValues(2));
                app.FValueLabel.Text = sprintf('%.3f', app.Population.BestIndivid.FValue);
                
                app.CurrentGenerationNumberLabel.Text = int2str(app.Population.GenerationNumber);
                
                app.TopValues = [app.TopValues; [app.Population.GenerationNumber, app.Population.BestIndivid.FValue]];
                app.MiddleValues = [app.MiddleValues; [app.Population.GenerationNumber, app.Population.MedianFValue]];
                
                hold(app.MiddleAndTopFitnessUIAxes, 'on');
                
                plot(app.MiddleAndTopFitnessUIAxes, app.MiddleValues(:,1), app.MiddleValues(:,2),"Color", 'blue');
                plot(app.MiddleAndTopFitnessUIAxes, app.TopValues(:,1), app.TopValues(:,2), "Color",'red');
                legend(app.MiddleAndTopFitnessUIAxes, "Среднее значение фитнесс-функции", "Лучшее значение фитнесс-функции");
                
                hold(app.MiddleAndTopFitnessUIAxes, 'off');
            else
                app.CurrentGenerationNumberLabel.Text = '-';
                
                app.X1ValueLabel.Text = '-';
                app.FValueLabel.Text = '-';
                
                cla(app.MiddleAndTopFitnessUIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplySettingsButton
        function ApplySettingsClick(app, event)
            applySettings(app);
            updateUI(app);
        end

        % Button pushed function: NextGenerationButton
        function NextGenerationClick(app, event)
            goToNextGeneration(app);
            updateUI(app);
        end

        % Button pushed function: ResetButton
        function ResetClick(app, event)
            reset(app);
            updateUI(app);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %cancel(app.ParallelWork);
            app.Stop = 1;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.Stop = 0;
            app.runToMaxGenerationNumber();
            % app.ParallelWork = parfeval(@doutoRun, 1, app);
            % res = fetchOutputs(app.ParallelWork);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FunctionUIAxes = uiaxes(app.UIFigure);
            title(app.FunctionUIAxes, 'Title')
            xlabel(app.FunctionUIAxes, 'X1')
            ylabel(app.FunctionUIAxes, 'x2')
            app.Label = uilabel(app.UIFigure);
            app.Label.Tooltip = {'Параметры'};
            app.Label.Text = {'Размер популяции'; ''};
            app.PopulationSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulationSizeEditField.Tooltip = {'Параметры'};
            app.PopulationSizeEditField.Value = 200;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Tooltip = {'Параметры'};
            app.Label_2.Text = 'Количество генов';
            app.GensCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.GensCountEditField.Tooltip = {'Параметры'};
            app.GensCountEditField.Value = 15;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Tooltip = {'Параметры'};
            app.EditField_3Label.Text = 'Вероятность кроссинговера';
            app.CrossingoverProbabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrossingoverProbabilityEditField.Tooltip = {'Параметры'};
            app.CrossingoverProbabilityEditField.Value = 0.5;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Tooltip = {'Параметры'};
            app.Label_3.Text = 'Вероятность мутации';
            app.MutationProbabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MutationProbabilityEditField.Tooltip = {'Параметры'};
            app.MutationProbabilityEditField.Value = 0.01;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Tooltip = {'Параметры'};
            app.Label_4.Text = 'Максимальное количество поколений';
            app.MaxGenerationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxGenerationNumberEditField.Tooltip = {'Параметры'};
            app.MaxGenerationNumberEditField.Value = 50;
            app.MiddleAndTopFitnessUIAxes = uiaxes(app.UIFigure);
            title(app.MiddleAndTopFitnessUIAxes, 'Title')
            xlabel(app.MiddleAndTopFitnessUIAxes, 'Номер поколения')
            ylabel(app.MiddleAndTopFitnessUIAxes, 'Значение фитнеса')
            app.ApplySettingsButton = uibutton(app.UIFigure, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsClick, true);
            app.ApplySettingsButton.Text = 'Применить параметры';
            app.NextGenerationButton = uibutton(app.UIFigure, 'push');
            app.NextGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @NextGenerationClick, true);
            app.NextGenerationButton.Text = 'Следующее поколение';
            app.X1Label = uilabel(app.UIFigure);
            app.X1Label.Text = 'X1:';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F:';
            app.label = uilabel(app.UIFigure);
            app.label.Text = 'Экстремум';
            app.X1ValueLabel = uilabel(app.UIFigure);
            app.X1ValueLabel.Text = {'-'; ''};
            app.FValueLabel = uilabel(app.UIFigure);
            app.FValueLabel.Text = {'-'; ''};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetClick, true);
            app.ResetButton.Text = 'Сбросить';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Левая граница поиска';
            app.SearchLeftXEditField = uieditfield(app.UIFigure, 'numeric');
            app.SearchLeftXEditField.Value = -10;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Правая граница поиска';
            app.SearchRightXEditField = uieditfield(app.UIFigure, 'numeric');
            app.SearchRightXEditField.Value = 10;
            app.label_2 = uilabel(app.UIFigure);
            app.label_2.Text = {'Текущее поколение: '; ''};
            app.CurrentGenerationNumberLabel = uilabel(app.UIFigure);
            app.CurrentGenerationNumberLabel.Text = {'-'; ''};
            app.X2Label = uilabel(app.UIFigure);
            app.X2Label.Text = 'X2:';
            app.X2ValueLabel = uilabel(app.UIFigure);
            app.X2ValueLabel.Text = {'-'; ''};
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Остановить';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Запустить';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Автоматическое выполнение';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab2_Nemkovich

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end