classdef lab3_Nemkovich < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FunctionUIAxes                matlab.ui.control.UIAxes
        EditField_3Label              matlab.ui.control.Label
        CrossingoverProbabilityEditField  matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        MutationProbabilityEditField  matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        MaxGenerationNumberEditField  matlab.ui.control.NumericEditField
        MiddleAndTopFitnessUIAxes     matlab.ui.control.UIAxes
        ApplySettingsButton           matlab.ui.control.Button
        NextGenerationButton          matlab.ui.control.Button
        FLabel                        matlab.ui.control.Label
        label                         matlab.ui.control.Label
        FValueLabel                   matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        label_2                       matlab.ui.control.Label
        CurrentGenerationNumberLabel  matlab.ui.control.Label
        PauseButton                   matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        Label_6                       matlab.ui.control.Label
        FilePathLabel                 matlab.ui.control.Label
        ChooseFileButton              matlab.ui.control.Button
        Label_7                       matlab.ui.control.Label
        PopulationSizeEditField       matlab.ui.control.NumericEditField
        Button                        matlab.ui.control.Button
        Label_8                       matlab.ui.control.Label
        BestWayTextArea               matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        FilePath = '';
        Data = [];
        CrossingoverProbability = 0
        MutationProbability = 0
        MaxGenerationNumber = 0
        Size = 0
        
        % Population state
        Population
        MiddleValues=[];
        TopValues=[];
        Stop = 1;
    end
    
    methods (Access = private)
        
        function readSettings(app)
            app.CrossingoverProbability = app.CrossingoverProbabilityEditField.Value;
            app.MutationProbability = app.MutationProbabilityEditField.Value;
            app.MaxGenerationNumber = app.MaxGenerationNumberEditField.Value;
            app.Size = app.PopulationSizeEditField.Value;
            app.FilePath = app.FilePathLabel.Text;
        end
        
        function generateInitialGeneration(app)
            app.Data = dlmread(app.FilePath);
            
            app.Population = population(app.Size, app.Data);
        end
        
        function goToNextGeneration(app)
            if isa(app.Population, 'population') && app.Population.GenerationNumber >= 1
                app.Population = app.Population.goToNextGeneration( ...
                    app.CrossingoverProbability, ...
                    app.MutationProbability);    
            end
        end
        
        function reset(app, stopParallel)
            doStop = true;
            
            if nargin > 1
                doStop = stopParallel;
            end
            
            if doStop
                app.Stop = 1;    
            end
            
            if isa(app.Population, 'population')
                app.Population = app.Population.reset();    
            end
            app.MiddleValues = [];
            app.TopValues = [];
        end
        
        function applySettings(app, stopParallel)
            doStop = true;
            
            if nargin > 1
                doStop = stopParallel;
            end
            
            reset(app, doStop);
            updateUI(app);
            readSettings(app);
            generateInitialGeneration(app);
        end
        
        function runToMaxGenerationNumber(app)
            pauseTime = 0.2;
            
            pause on
            
            if false == isa(app.Population, 'population') || app.Population.GenerationNumber == 0
                applySettings(app, false);
                app.updateUI();
                pause(pauseTime);
            end
            
            for i=app.Population.GenerationNumber:app.MaxGenerationNumber-1
                if app.Stop > 0
                    app.Stop = 0;
                    break;
                end
                app.goToNextGeneration();
                app.updateUI();
                pause(pauseTime);
            end
            
            pause off
        end
        
        function renderBestWay(app)
            if isa(app.Population, 'population') && app.Population.GenerationNumber > 0
                x = [];
                y = [];
                bestWay = app.Population.BestIndivid.Chromosomes;
                for i=1:app.Population.GenCount
                    x = [x, app.Data(bestWay(i), 2)];
                    y = [y, app.Data(bestWay(i), 3)];
                end
                
                plot(app.FunctionUIAxes, x, y, 'o-');
                
                app.BestWayTextArea.Value = mat2str(bestWay);
            end
        end
        
        function updateUI(app)
            if false == isa(app.Population, 'population')
                return;
            end
            
            if app.Population.GenerationNumber > 0
                cla(app.FunctionUIAxes);
                
                app.CurrentGenerationNumberLabel.Text = int2str(app.Population.GenerationNumber);
                
                %render best way
                app.renderBestWay();
                
                %render other
                app.FValueLabel.Text = sprintf('%.3f', app.Population.BestIndivid.FValue);
                
                app.CurrentGenerationNumberLabel.Text = int2str(app.Population.GenerationNumber);
                
                app.TopValues = [app.TopValues; [app.Population.GenerationNumber, app.Population.BestIndivid.FValue]];
                app.MiddleValues = [app.MiddleValues; [app.Population.GenerationNumber, app.Population.MedianFValue]];
                
                hold(app.MiddleAndTopFitnessUIAxes, 'on');
                
                plot(app.MiddleAndTopFitnessUIAxes, app.MiddleValues(:,1), app.MiddleValues(:,2),"Color", 'blue');
                plot(app.MiddleAndTopFitnessUIAxes, app.TopValues(:,1), app.TopValues(:,2), "Color",'red');
                legend(app.MiddleAndTopFitnessUIAxes, "Среднее значение фитнесс-функции", "Лучшее значение фитнесс-функции");
                
                hold(app.MiddleAndTopFitnessUIAxes, 'off');
            else
                app.CurrentGenerationNumberLabel.Text = '-';
                
                app.FValueLabel.Text = '-';
                app.BestWayTextArea.Value = '-';
                
                cla(app.MiddleAndTopFitnessUIAxes);
                cla(app.FunctionUIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplySettingsButton
        function ApplySettingsClick(app, event)
            applySettings(app);
            updateUI(app);
        end

        % Button pushed function: NextGenerationButton
        function NextGenerationClick(app, event)
            goToNextGeneration(app);
            updateUI(app);
        end

        % Button pushed function: ResetButton
        function ResetClick(app, event)
            reset(app);
            updateUI(app);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %cancel(app.ParallelWork);
            app.Stop = 1;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.Stop = 0;
            app.runToMaxGenerationNumber();
            % app.ParallelWork = parfeval(@doutoRun, 1, app);
            % res = fetchOutputs(app.ParallelWork);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.renderBestWay();
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            if file ~= 0
                fullpath = fullfile(path,file);
                app.FilePath = fullpath;    
                app.FilePathLabel.Text = fullpath;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FunctionUIAxes = uiaxes(app.UIFigure);
            title(app.FunctionUIAxes, 'Title')
            xlabel(app.FunctionUIAxes, 'X')
            ylabel(app.FunctionUIAxes, 'Y')
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Tooltip = {'Параметры'};
            app.EditField_3Label.Text = 'Вероятность кроссинговера';
            app.CrossingoverProbabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrossingoverProbabilityEditField.Tooltip = {'Параметры'};
            app.CrossingoverProbabilityEditField.Value = 0.4;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Tooltip = {'Параметры'};
            app.Label_3.Text = 'Вероятность мутации';
            app.MutationProbabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MutationProbabilityEditField.Tooltip = {'Параметры'};
            app.MutationProbabilityEditField.Value = 0.05;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Tooltip = {'Параметры'};
            app.Label_4.Text = 'Максимальное количество поколений';
            app.MaxGenerationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxGenerationNumberEditField.Tooltip = {'Параметры'};
            app.MaxGenerationNumberEditField.Value = 350;
            app.MiddleAndTopFitnessUIAxes = uiaxes(app.UIFigure);
            title(app.MiddleAndTopFitnessUIAxes, 'Title')
            xlabel(app.MiddleAndTopFitnessUIAxes, 'Номер поколения')
            ylabel(app.MiddleAndTopFitnessUIAxes, 'Значение фитнеса')
            app.ApplySettingsButton = uibutton(app.UIFigure, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsClick, true);
            app.ApplySettingsButton.Text = 'Применить параметры';
            app.NextGenerationButton = uibutton(app.UIFigure, 'push');
            app.NextGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @NextGenerationClick, true);
            app.NextGenerationButton.Text = 'Следующее поколение';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F:';
            app.label = uilabel(app.UIFigure);
            app.label.Text = 'Экстремум';
            app.FValueLabel = uilabel(app.UIFigure);
            app.FValueLabel.Text = {'-'; ''};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetClick, true);
            app.ResetButton.Text = 'Сбросить';
            app.label_2 = uilabel(app.UIFigure);
            app.label_2.Text = {'Текущее поколение: '; ''};
            app.CurrentGenerationNumberLabel = uilabel(app.UIFigure);
            app.CurrentGenerationNumberLabel.Text = {'-'; ''};
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Остановить';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Запустить';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Автоматическое выполнение';
            app.FilePathLabel = uilabel(app.UIFigure);
            app.FilePathLabel.Text = 'C:\Users\Asus\Desktop\test.txt';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Выбрать файл';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Tooltip = {'Параметры'};
            app.Label_7.Text = {'Размер популяции'; ''};
            app.PopulationSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulationSizeEditField.Tooltip = {'Параметры'};
            app.PopulationSizeEditField.Value = 100;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Отобразить лучший маршрут';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'Лучший маршрут';
            app.BestWayTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3_Nemkovich

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end